classdef SNOM_Spectra < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ShowUserSpectrum                matlab.ui.control.CheckBox
        UploadComment_2                 matlab.ui.control.Label
        UploadthespectrumButton         matlab.ui.control.Button
        DownloadanexampleButton         matlab.ui.control.Button
        UploadComment                   matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        SavetheresultsButton            matlab.ui.control.Button
        NumberPoint                     matlab.ui.control.NumericEditField
        NumberofpointsEditFieldLabel    matlab.ui.control.Label
        ShowthetabledataButton          matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        Range_max                       matlab.ui.control.NumericEditField
        toEditFieldLabel                matlab.ui.control.Label
        Range_min                       matlab.ui.control.NumericEditField
        PlotthespectrumfromEditFieldLabel  matlab.ui.control.Label
        FrequencyrangeininversecmLabel  matlab.ui.control.Label
        HarmonicNumber                  matlab.ui.control.NumericEditField
        DielectricpermittivityoftherefeReEditFieldLabel_3  matlab.ui.control.Label
        ImEpsRefField                   matlab.ui.control.NumericEditField
        ImEditField_2Label              matlab.ui.control.Label
        ReEpsRefField                   matlab.ui.control.NumericEditField
        DielectricpermittivityoftherefeReEditFieldLabel  matlab.ui.control.Label
        UploadthefileButton             matlab.ui.control.Button
        ChoosethematerialDropDown       matlab.ui.control.DropDown
        ChoosethematerialDropDownLabel  matlab.ui.control.Label
        SampleparametersLabel           matlab.ui.control.Label
        ThetaField                      matlab.ui.control.NumericEditField
        IllumitnationangledegLabel      matlab.ui.control.Label
        cField                          matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        FFCheck                         matlab.ui.control.CheckBox
        Farfieldcontribution1r2Label    matlab.ui.control.Label
        ImEpsTipField                   matlab.ui.control.NumericEditField
        ImEditFieldLabel                matlab.ui.control.Label
        ReEpsTipField                   matlab.ui.control.NumericEditField
        DielectricpermittivityofthetipReLabel  matlab.ui.control.Label
        H02Field                        matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        H01Field                        matlab.ui.control.NumericEditField
        MinimaltipsampledistanceH_0nmLabel  matlab.ui.control.Label
        AField                          matlab.ui.control.NumericEditField
        TappingamplitudeAnmEditFieldLabel  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        LField                          matlab.ui.control.NumericEditField
        LengthLnmEditFieldLabel         matlab.ui.control.Label
        RField                          matlab.ui.control.NumericEditField
        CurvatureradiusRnmLabel         matlab.ui.control.Label
        TipparametersLabel              matlab.ui.control.Label
        AlgorithvariantButtonGroup      matlab.ui.container.ButtonGroup
        MEditField                      matlab.ui.control.NumericEditField
        MEditFieldLabel                 matlab.ui.control.Label
        ModifydiscretizationparametersCheckBox  matlab.ui.control.CheckBox
        NEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        AccurateButton                  matlab.ui.control.RadioButton
        FastButton                      matlab.ui.control.RadioButton
        Label_3                         matlab.ui.control.Label
        sSNOMspectraofthebulksamplesLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        RightPanel                      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_1                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

       properties (Access = public)
        SpecResults
        UserData
        UserSpectrum
        StopRequested logical = false
       end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MEditField.Enable='off';
            app.NEditField.Enable='off';
            app.MEditField.Value=50;
            app.NEditField.Value=50;
            app.ModifydiscretizationparametersCheckBox.Enable='off';
            app.Farfieldcontribution1r2Label.Interpreter = "html";
            app.Farfieldcontribution1r2Label.Text='Far-field contribution, (1+cr<sub>p</sub>)<sup>2</sup>';
            if app.FFCheck.Value
                app.cField.Enable = 'on';
                app.ThetaField.Enable = 'on';
            else
                app.cField.Enable = 'off';
                app.ThetaField.Enable = 'off';
            end
            ddV = app.ChoosethematerialDropDown.Value;
            switch ddV
                case 'Upload the file'
                    app.UploadthefileButton.Enable = 'on';
                    app.CalculateButton.Enable = 'off';
                    app.DownloadanexampleButton.Enable = 'on';
                case 'PMMA'
                    app.UploadthefileButton.Enable = 'off';
                    app.CalculateButton.Enable = 'on';
                    app.DownloadanexampleButton.Enable = 'off';
                case 'Choose the material'
                    app.UploadthefileButton.Enable = 'off';
                    app.CalculateButton.Enable = 'off';
                    app.DownloadanexampleButton.Enable = 'off';
                case '4HSiC'
                    app.UploadthefileButton.Enable = 'off';
                    app.CalculateButton.Enable = 'on';
                    app.DownloadanexampleButton.Enable = 'off';
                case '6HSiC'
                    app.UploadthefileButton.Enable = 'off';
                    app.CalculateButton.Enable = 'on';
                    app.DownloadanexampleButton.Enable = 'off';
                case 'Quartz'
                    app.UploadthefileButton.Enable = 'off';
                    app.CalculateButton.Enable = 'on';
                    app.DownloadanexampleButton.Enable = 'off';
            end
            app.SavetheresultsButton.Enable = 'off';
            app.ShowthetabledataButton.Enable = 'off';
            app.UploadthespectrumButton.Enable = 'off';
            app.UploadComment_2.Enable = 'off';
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            cla(app.UIAxes_1);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            app.CalculateButton.Enable = 'off';
            app.StopRequested = false;

            drawnow;
            
            EpsS1 = app.ReEpsRefField.Value;
            EpsS2 = app.ImEpsRefField.Value;
            R = app.RField.Value;
            L = app.LField.Value;
            A = app.AField.Value;
            Hmin = app.H01Field.Value;
            Hmin0 = app.H02Field.Value;
            EpsT1 = app.ReEpsTipField.Value;
            EpsT2 = app.ImEpsTipField.Value;
            phi = app.ThetaField.Value/180*pi;
            const = app.cField.Value;
            n = app.HarmonicNumber.Value;
            om_min = app.Range_min.Value;
            om_max = app.Range_max.Value;
            Num = app.NumberPoint.Value;
            ddValue = app.ChoosethematerialDropDown.Value;
            N_acc=app.NEditField.Value;
            M_acc=app.MEditField.Value;
            switch ddValue
                case 'Upload the file'
                    flag = 1;
                case 'PMMA'
                    flag = 2;
                case 'Quartz'
                    flag = 3;
                case '4HSiC'
                    flag = 4;
                case '6HSiC'
                    flag = 5;
            end
            FF = app.FFCheck.Value;

            % Calculate
            if (flag==1)
                if ~isempty(app.UserData)
                    if app.FastButton.Value
                        Spec = BulkSpectrum(EpsS1, EpsS2, R, L, A, Hmin, Hmin0, EpsT1, EpsT2, phi, const, n, om_min, om_max, Num, FF, flag, app.UserData);
                    elseif app.AccurateButton.Value
                        Spec = BulkSpectrum_ac(EpsS1, EpsS2, R, L, A, Hmin, Hmin0, EpsT1, EpsT2, phi, const, n, om_min, om_max, Num, FF, flag, app.UserData, N_acc, M_acc);
                    end
                    % Plot the graph
                    % Dielectric permittivity
                    plot(app.UIAxes_1, Spec(:,1), Spec(:,4), "-b", Spec(:,1), Spec(:,5), "-r", LineWidth=2);
                    legend(app.UIAxes_1, 'Re($\varepsilon$)', 'Im($\varepsilon$)', 'fontsize', 9, 'interpreter','latex');
                
                    if (~isempty(app.UserSpectrum) && app.ShowUserSpectrum.Value)
                        % Amplitude
                        plot(app.UIAxes_2, Spec(:,1), Spec(:,2), "-b", app.UserSpectrum(:,1), app.UserSpectrum(:,2), ":r", LineWidth=2);
                        legend(app.UIAxes_2, 'Calculated spectrum', 'Uploaded spectrum', 'fontsize', 9, 'interpreter','latex');
                        % Phase
                        plot(app.UIAxes_3, Spec(:,1), Spec(:,3), "-b", app.UserSpectrum(:,1), app.UserSpectrum(:,3), ":r", LineWidth=2);
                        legend(app.UIAxes_3, 'Calculated spectrum', 'Uploaded spectrum', 'fontsize', 9, 'interpreter','latex');
                    else
                        % Amplitude
                        plot(app.UIAxes_2, Spec(:,1), Spec(:,2), "-b", LineWidth=2);
                        legend(app.UIAxes_2, 'Calculated spectrum', 'fontsize', 9, 'interpreter','latex');
                        % Phase
                        plot(app.UIAxes_3, Spec(:,1), Spec(:,3), "-b", LineWidth=2);
                        legend(app.UIAxes_3, 'Calculated spectrum', 'fontsize', 9, 'interpreter','latex');
                    end

                    % Save the results
                    app.SpecResults = Spec;

                    app.SavetheresultsButton.Enable = 'on';
                    app.ShowthetabledataButton.Enable = 'on';
                    app.CalculateButton.Enable = 'on';
                else
                    uialert(app.UIFigure, 'No dielectric permittivity data found!', 'Warning');
                end
            else
                if app.FastButton.Value
                    Spec = BulkSpectrum(EpsS1, EpsS2, R, L, A, Hmin, Hmin0, EpsT1, EpsT2, phi, const, n, om_min, om_max, Num, FF, flag, 0);
                elseif app.AccurateButton.Value
                    Spec = BulkSpectrum_ac(EpsS1, EpsS2, R, L, A, Hmin, Hmin0, EpsT1, EpsT2, phi, const, n, om_min, om_max, Num, FF, flag, 0, N_acc, M_acc);
                end          
                % Plot the graph
                % Dielectric permittivity
                plot(app.UIAxes_1, Spec(:,1), Spec(:,4), "-b", Spec(:,1), Spec(:,5), "-r", LineWidth=2);
                legend(app.UIAxes_1, 'Re($\varepsilon$)', 'Im($\varepsilon$)', 'fontsize', 9, 'interpreter','latex');
                
                if (~isempty(app.UserSpectrum) && app.ShowUserSpectrum.Value)
                     % Amplitude
                     plot(app.UIAxes_2, Spec(:,1), Spec(:,2), "-b", app.UserSpectrum(:,1), app.UserSpectrum(:,2), ":r", LineWidth=2);
                     legend(app.UIAxes_2, 'Calculated spectrum', 'Uploaded spectrum', 'fontsize', 9, 'interpreter','latex');
                     % Phase
                     plot(app.UIAxes_3, Spec(:,1), Spec(:,3), "-b", app.UserSpectrum(:,1), app.UserSpectrum(:,3), ":r", LineWidth=2);
                     legend(app.UIAxes_3, 'Calculated spectrum', 'Uploaded spectrum', 'fontsize', 9, 'interpreter','latex');
                else
                    % Amplitude
                    plot(app.UIAxes_2, Spec(:,1), Spec(:,2), "-b", LineWidth=2);
                    legend(app.UIAxes_2, 'Calculated spectrum', 'fontsize', 9, 'interpreter','latex');
                    % Phase
                    plot(app.UIAxes_3, Spec(:,1), Spec(:,3), "-b", LineWidth=2);
                    legend(app.UIAxes_3, 'Calculated spectrum', 'fontsize', 9, 'interpreter','latex');
                end
                
                % Save the results
                app.SpecResults = Spec;

                app.SavetheresultsButton.Enable = 'on';
                app.ShowthetabledataButton.Enable = 'on';
                app.CalculateButton.Enable = 'on';
            end
        end

        % Value changed function: FFCheck
        function FFCheckValueChanged(app, event)
        if app.FFCheck.Value
            app.cField.Enable = 'on';
            app.ThetaField.Enable = 'on';
        else
            app.cField.Enable = 'off';
            app.ThetaField.Enable = 'off';
        end
        end

        % Value changed function: ChoosethematerialDropDown
        function ChoosethematerialDropDownValueChanged(app, event)
            ddV = app.ChoosethematerialDropDown.Value;
            switch ddV
                case 'Upload the file'
                    app.UploadthefileButton.Enable = 'on';
                    app.CalculateButton.Enable = 'off';
                    app.DownloadanexampleButton.Enable = 'on';
                case 'PMMA'
                    app.UploadthefileButton.Enable = 'off';
                    app.CalculateButton.Enable = 'on';
                    app.DownloadanexampleButton.Enable = 'off';
                case 'Choose the material'
                    app.UploadthefileButton.Enable = 'off';
                    app.CalculateButton.Enable = 'off';
                    app.DownloadanexampleButton.Enable = 'off';
                case '4HSiC'
                    app.UploadthefileButton.Enable = 'off';
                    app.CalculateButton.Enable = 'on';
                    app.DownloadanexampleButton.Enable = 'off';
                case '6HSiC'
                    app.UploadthefileButton.Enable = 'off';
                    app.CalculateButton.Enable = 'on';
                    app.DownloadanexampleButton.Enable = 'off';
                case 'Quartz'
                    app.UploadthefileButton.Enable = 'off';
                    app.CalculateButton.Enable = 'on';
                    app.DownloadanexampleButton.Enable = 'off';
            end
        end

        % Button pushed function: ShowthetabledataButton
        function ShowthetabledataButtonPushed(app, event)
            if ~isempty(app.SpecResults)
            fig = uifigure('Name', 'Spectrum');
            uitable(fig, 'Data', app.SpecResults, ...
            'ColumnName', {'Frequency', 'Amplitude', 'Phase, rad', 'Real part of the dielectric permittivity', 'Imaginary part of the dielectric permittivity'}, ...
            'Position', [20 20 600 400]);
            else
                uialert(app.UIFigure, 'No results found!', 'Warning');
            end
        end

        % Button pushed function: SavetheresultsButton
        function SavetheresultsButtonPushed(app, event)
            if ~isempty(app.SpecResults)
                [file,path] = uiputfile('*.xlsx','Save as');
                if file ~= 0
                    fullpath = fullfile(path, file);
                    Spec = app.SpecResults;
            
                    headers = {'Frequency, cm-1','Amplitude','Phase, rad','Dielectric permittivity real part','Dielectric permittivity imaginary part'};
            
                    T = array2table(Spec, 'VariableNames', headers);
                    writetable(T, fullpath);

                    uialert(app.UIFigure, 'The data is saved successfully', 'Success');
                end
            else
                uialert(app.UIFigure, 'No results found!', 'Error');
            end
        end

        % Button pushed function: UploadthefileButton
        function UploadthefileButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.txt','Text Files (*.txt)';'*.*','All Files'},'Choose the file');
            if filename ~= 0
                filepath = fullfile(pathname, filename);
                % Uploading the file
                app.UserData = load(filepath);
                % Notification
                uialert(app.UIFigure, 'File uploaded successfully', 'Success');
                app.CalculateButton.Enable = 'on';
            else
                uialert(app.UIFigure, 'File not selected', 'Warning');
            end
        end

        % Selection changed function: AlgorithvariantButtonGroup
        function AlgorithvariantButtonGroupSelectionChanged(app, event)
                    if app.FastButton.Value
                        app.ModifydiscretizationparametersCheckBox.Enable='off';
                        app.MEditField.Enable = 'off';
                        app.NEditField.Enable = 'off';
                        app.ModifydiscretizationparametersCheckBox.Value=0;
                        app.MEditField.Value=50;
                        app.NEditField.Value=50;
                    elseif app.AccurateButton.Value
                        app.ModifydiscretizationparametersCheckBox.Enable='on';
                    end
        end

        % Value changed function: ModifydiscretizationparametersCheckBox
        function ModifydiscretizationparametersCheckBoxValueChanged(app, event)
            if app.ModifydiscretizationparametersCheckBox.Value
                app.MEditField.Enable = 'on';
                app.NEditField.Enable = 'on';
            else
                app.MEditField.Enable = 'off';
                app.NEditField.Enable = 'off';
                app.MEditField.Value=50;
                app.NEditField.Value=50;
            end
        end

        % Button pushed function: DownloadanexampleButton
        function DownloadanexampleButtonPushed(app, event)
            [file,path] = uiputfile('*.txt','Save as');
            if file ~= 0
                fullpath = fullfile(path, file);
                EpsRM=load('real_eps_PMMA.txt');
                EpsIM=load('imag_eps_PMMA.txt');
                EpsPMMA=[EpsRM(:,1),EpsRM(:,2),EpsIM(:,2)];
                writematrix(EpsPMMA, fullpath);
                uialert(app.UIFigure, 'The file is downloaded successfully', 'Success');
            end
        end

        % Value changed function: ShowUserSpectrum
        function ShowUserSpectrumValueChanged(app, event)
            if app.ShowUserSpectrum.Value
                app.UploadthespectrumButton.Enable = 'on';
                app.UploadComment_2.Enable = 'on';
            else
                app.UploadthespectrumButton.Enable = 'off';
                app.UploadComment_2.Enable = 'off';
            end
        end

        % Button pushed function: UploadthespectrumButton
        function UploadthespectrumButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.txt','Text Files (*.txt)';'*.*','All Files'},'Choose the file');
            if filename ~= 0
                filepath = fullfile(pathname, filename);
                % Uploading the file
                app.UserSpectrum = load(filepath);
                % Notification
                uialert(app.UIFigure, 'File uploaded successfully', 'Success');
            else
                uialert(app.UIFigure, 'File not selected', 'Warning');
            end
        end

        % Callback function: not associated with a component
        function StopButtonPushed(app, event)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {768, 768};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {621, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'dipc_logo.png');
            app.sSNOMspectraofthebulksamplesLabel = uilabel(app.LeftPanel);
            app.sSNOMspectraofthebulksamplesLabel.Text = ' s-SNOM spectra of the bulk samples';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = {'This application allows users to calculate the near-field spectrum of bulk homogeneous and isotropic sample.'; ''};
            app.AlgorithvariantButtonGroup = uibuttongroup(app.LeftPanel);
            app.AlgorithvariantButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlgorithvariantButtonGroupSelectionChanged, true);
            app.AlgorithvariantButtonGroup.Title = 'The algorithm is available in several variants, each with distinct advantages and limitations.';
            app.FastButton = uiradiobutton(app.AlgorithvariantButtonGroup);
            app.FastButton.Text = {'Fast. This variant is suitable for non-resonant or weakly resonant samples characterized by positive or large negative dielectric'; 'permittivity. However, it produces notable inaccuracies for samples with dielectric permittivity of the order of -1, with the error'; 'increasing as the tip-sample distance decreases. '};
            app.FastButton.Value = true;
            app.AccurateButton = uiradiobutton(app.AlgorithvariantButtonGroup);
            app.AccurateButton.Text = {'Accurate. This variant, while suitable for all samples, is computationally intensive (takes minutes instead of seconds); therefore,'; 'we recommend employing it primarly for resonant samples exhibiting negative permittivity.'};
            app.NEditFieldLabel = uilabel(app.AlgorithvariantButtonGroup);
            app.NEditFieldLabel.Text = 'N=';
            app.NEditField = uieditfield(app.AlgorithvariantButtonGroup, 'numeric');
            app.NEditField.Value = 50;
            app.ModifydiscretizationparametersCheckBox = uicheckbox(app.AlgorithvariantButtonGroup);
            app.ModifydiscretizationparametersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ModifydiscretizationparametersCheckBoxValueChanged, true);
            app.ModifydiscretizationparametersCheckBox.Tooltip = {'Adjust the model''s discretization parameters: N, the number of points for tip-sample distance discretization used in the Fourier transform, and M, the matrix size defined in Equation 3 of the main text. Higher values improve convergence but increase computation time. Default settings are typically sufficient, but extreme tip or material parameters may cause numerical artifacts (e.g., oscillations near the amplitude peak). In such cases, increasing N and M can help resolve these issues.'};
            app.ModifydiscretizationparametersCheckBox.Text = 'Modify discretization parameters to adjust calculation presicion and rate';
            app.MEditFieldLabel = uilabel(app.AlgorithvariantButtonGroup);
            app.MEditFieldLabel.Text = 'M=';
            app.MEditField = uieditfield(app.AlgorithvariantButtonGroup, 'numeric');
            app.MEditField.Value = 40;
            app.TipparametersLabel = uilabel(app.LeftPanel);
            app.TipparametersLabel.Text = 'Tip parameters';
            app.CurvatureradiusRnmLabel = uilabel(app.LeftPanel);
            app.CurvatureradiusRnmLabel.Text = 'Curvature radius, R, nm';
            app.RField = uieditfield(app.LeftPanel, 'numeric');
            app.RField.Value = 25;
            app.LengthLnmEditFieldLabel = uilabel(app.LeftPanel);
            app.LengthLnmEditFieldLabel.Text = 'Length, L, nm';
            app.LField = uieditfield(app.LeftPanel, 'numeric');
            app.LField.Value = 600;
            app.Image2 = uiimage(app.LeftPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Tip_scheme.jpg');
            app.TappingamplitudeAnmEditFieldLabel = uilabel(app.LeftPanel);
            app.TappingamplitudeAnmEditFieldLabel.Text = 'Tapping amplitude, A, nm';
            app.AField = uieditfield(app.LeftPanel, 'numeric');
            app.AField.Value = 40;
            app.MinimaltipsampledistanceH_0nmLabel = uilabel(app.LeftPanel);
            app.MinimaltipsampledistanceH_0nmLabel.Interpreter = 'html';
            app.MinimaltipsampledistanceH_0nmLabel.Text = 'Minimum tip-sample distance, H<sub>0,s</sub>, nm';
            app.H01Field = uieditfield(app.LeftPanel, 'numeric');
            app.H01Field.Value = 2;
            app.EditFieldLabel = uilabel(app.LeftPanel);
            app.EditFieldLabel.Interpreter = 'html';
            app.EditFieldLabel.Text = 'Minimum distance between the tip and the reference material, H<sub>0,r</sub>, nm';
            app.H02Field = uieditfield(app.LeftPanel, 'numeric');
            app.H02Field.Tooltip = {'Since the minimum distance between the tip and the surface of the material depends not only on the parameters of the tip, but also on the properties of the material itself, it can be different for the sample and the reference material, even if all other parameters (R, A, etc.), as assumed here, are the same.'};
            app.H02Field.Value = 2;
            app.DielectricpermittivityofthetipReLabel = uilabel(app.LeftPanel);
            app.DielectricpermittivityofthetipReLabel.Text = 'Dielectric permittivity of the tip, Re(ε):';
            app.ReEpsTipField = uieditfield(app.LeftPanel, 'numeric');
            app.ReEpsTipField.Value = -1000;
            app.ImEditFieldLabel = uilabel(app.LeftPanel);
            app.ImEditFieldLabel.Text = 'Im(ε):';
            app.ImEpsTipField = uieditfield(app.LeftPanel, 'numeric');
            app.ImEpsTipField.Value = 800;
            app.Farfieldcontribution1r2Label = uilabel(app.LeftPanel);
            app.Farfieldcontribution1r2Label.Text = 'Far-field contribution, (1+ςr)2';
            app.FFCheck = uicheckbox(app.LeftPanel);
            app.FFCheck.ValueChangedFcn = createCallbackFcn(app, @FFCheckValueChanged, true);
            app.FFCheck.Text = 'Take into account far-field multiplier.';
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = 'ς=';
            app.cField = uieditfield(app.LeftPanel, 'numeric');
            app.cField.Tooltip = {'c is a reflected wave contribution modifier that is used to account for the influence of far-field-scale sample surface inhomogeneity, focused beam field inhomogeneity, and other far-field corrections. '};
            app.cField.Value = 1;
            app.IllumitnationangledegLabel = uilabel(app.LeftPanel);
            app.IllumitnationangledegLabel.Text = 'Illumitnation angle, θ, deg';
            app.ThetaField = uieditfield(app.LeftPanel, 'numeric');
            app.ThetaField.Value = 45;
            app.SampleparametersLabel = uilabel(app.LeftPanel);
            app.SampleparametersLabel.Text = 'Sample parameters';
            app.ChoosethematerialDropDownLabel = uilabel(app.LeftPanel);
            app.ChoosethematerialDropDownLabel.Text = 'Choose the material';
            app.ChoosethematerialDropDown = uidropdown(app.LeftPanel);
            app.ChoosethematerialDropDown.Items = {'Choose the material', 'Upload the file', 'PMMA', 'Quartz', '4HSiC', '6HSiC'};
            app.ChoosethematerialDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosethematerialDropDownValueChanged, true);
            app.ChoosethematerialDropDown.Value = 'Choose the material';
            app.UploadthefileButton = uibutton(app.LeftPanel, 'push');
            app.UploadthefileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadthefileButtonPushed, true);
            app.UploadthefileButton.Tooltip = {''};
            app.UploadthefileButton.Text = 'Upload the file';
            app.DielectricpermittivityoftherefeReEditFieldLabel = uilabel(app.LeftPanel);
            app.DielectricpermittivityoftherefeReEditFieldLabel.Text = 'Dielectric permittivity of the reference material: Re(ε):';
            app.ReEpsRefField = uieditfield(app.LeftPanel, 'numeric');
            app.ReEpsRefField.Value = -5000;
            app.ImEditField_2Label = uilabel(app.LeftPanel);
            app.ImEditField_2Label.Text = 'Im(ε):';
            app.ImEpsRefField = uieditfield(app.LeftPanel, 'numeric');
            app.ImEpsRefField.Value = 1000;
            app.DielectricpermittivityoftherefeReEditFieldLabel_3 = uilabel(app.LeftPanel);
            app.DielectricpermittivityoftherefeReEditFieldLabel_3.Text = 'Harmonic number, n=';
            app.HarmonicNumber = uieditfield(app.LeftPanel, 'numeric');
            app.HarmonicNumber.Value = 3;
            app.FrequencyrangeininversecmLabel = uilabel(app.LeftPanel);
            app.FrequencyrangeininversecmLabel.Text = 'Frequency range, in inverse cm';
            app.PlotthespectrumfromEditFieldLabel = uilabel(app.LeftPanel);
            app.PlotthespectrumfromEditFieldLabel.Text = 'Plot the spectrum from';
            app.Range_min = uieditfield(app.LeftPanel, 'numeric');
            app.Range_min.Value = 1650;
            app.toEditFieldLabel = uilabel(app.LeftPanel);
            app.toEditFieldLabel.Text = 'to';
            app.Range_max = uieditfield(app.LeftPanel, 'numeric');
            app.Range_max.Value = 1800;
            app.CalculateButton = uibutton(app.LeftPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ShowthetabledataButton = uibutton(app.LeftPanel, 'push');
            app.ShowthetabledataButton.ButtonPushedFcn = createCallbackFcn(app, @ShowthetabledataButtonPushed, true);
            app.ShowthetabledataButton.Text = 'Show the results';
            app.NumberofpointsEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofpointsEditFieldLabel.Text = 'Number of points =';
            app.NumberPoint = uieditfield(app.LeftPanel, 'numeric');
            app.NumberPoint.Value = 100;
            app.SavetheresultsButton = uibutton(app.LeftPanel, 'push');
            app.SavetheresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SavetheresultsButtonPushed, true);
            app.SavetheresultsButton.Text = 'Save the results';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = {'Note: Sample is the material under investigation with the dispersive dielectric permittivity.'; 'Spectrum is normalized to the signal from the reference material with constant permittivity.'};
            app.UploadComment = uilabel(app.LeftPanel);
            app.UploadComment.Text = {'Upload a file (.txt) consisting of'; 'three columns: frequency in'; 'inverse centimeters, real and'; 'imaginary parts of permittivity.'; 'Columns are comma-separated'; 'or tab-separated.'};
            app.DownloadanexampleButton = uibutton(app.LeftPanel, 'push');
            app.DownloadanexampleButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadanexampleButtonPushed, true);
            app.DownloadanexampleButton.Text = 'Download an example';
            app.UploadthespectrumButton = uibutton(app.LeftPanel, 'push');
            app.UploadthespectrumButton.ButtonPushedFcn = createCallbackFcn(app, @UploadthespectrumButtonPushed, true);
            app.UploadthespectrumButton.Text = 'Upload the spectrum';
            app.UploadComment_2 = uilabel(app.LeftPanel);
            app.UploadComment_2.Text = {'Upload a file in the same format as used for'; 'dielectric permittivity data before calculating.'};
            app.ShowUserSpectrum = uicheckbox(app.LeftPanel);
            app.ShowUserSpectrum.ValueChangedFcn = createCallbackFcn(app, @ShowUserSpectrumValueChanged, true);
            app.ShowUserSpectrum.Text = 'Show the User''s spectrum (e.g., experimental) in the same plot.';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes_1 = uiaxes(app.GridLayout2);
            title(app.UIAxes_1, 'Dielectric permittivity, ε')
            xlabel(app.UIAxes_1, '\omega, cm^{-1}')
            ylabel(app.UIAxes_1, 'Dielectric permittivity')
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_2 = uiaxes(app.GridLayout2);
            title(app.UIAxes_2, 'Near-field amplitude, |\sigma_n|')
            xlabel(app.UIAxes_2, '\omega, cm^{-1}')
            ylabel(app.UIAxes_2, '|\sigma_n|')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.GridLayout2);
            title(app.UIAxes_3, 'Near-field phase, arg(\sigma_n)')
            xlabel(app.UIAxes_3, '\omega, cm^{-1}')
            ylabel(app.UIAxes_3, 'arg(\sigma_n), rad')
            zlabel(app.UIAxes_3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNOM_Spectra

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end